classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        GridLayout5                    matlab.ui.container.GridLayout
        GridLayout13                   matlab.ui.container.GridLayout
        EditField_4                    matlab.ui.control.NumericEditField
        Label_3                        matlab.ui.control.Label
        EditField_5                    matlab.ui.control.NumericEditField
        EditField_5Label               matlab.ui.control.Label
        DatarangeLabel                 matlab.ui.control.Label
        GridLayout12                   matlab.ui.container.GridLayout
        EditField_2                    matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        MaximumresolutionLabel         matlab.ui.control.Label
        GridLayout11                   matlab.ui.container.GridLayout
        InterpolatetimestepsindataLabel  matlab.ui.control.Label
        CheckBox_2                     matlab.ui.control.CheckBox
        GridLayout10                   matlab.ui.container.GridLayout
        EnableparallelizationLabel     matlab.ui.control.Label
        CheckBox                       matlab.ui.control.CheckBox
        GridLayout9                    matlab.ui.container.GridLayout
        Button_4                       matlab.ui.control.Button
        OutputfileEditField            matlab.ui.control.EditField
        OutputfileEditFieldLabel       matlab.ui.control.Label
        GridLayout8                    matlab.ui.container.GridLayout
        Button_3                       matlab.ui.control.Button
        MapfileEditField               matlab.ui.control.EditField
        MapfileEditFieldLabel          matlab.ui.control.Label
        GridLayout7                    matlab.ui.container.GridLayout
        Button_2                       matlab.ui.control.Button
        VisualizationdataEditField     matlab.ui.control.EditField
        VisualizationdataEditFieldLabel  matlab.ui.control.Label
        GridLayout6                    matlab.ui.container.GridLayout
        CoordinatesdataEditField       matlab.ui.control.EditField
        CoordinatesdataEditFieldLabel  matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        GridLayout4                    matlab.ui.container.GridLayout
        RenderButton                   matlab.ui.control.Button
        PreviewButton                  matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        Image4                         matlab.ui.control.Image
        Image3                         matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [file, path] = uigetfile({'*.txt;*.dat;*.csv;*.xls;*.xlsx;*.ods'; '*.*'});
            
            if ~isequal(file, 0)
                app.CoordinatesdataEditField.Value = [path file];
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [file, path] = uigetfile({'*.txt;*.dat;*.csv;*.xls;*.xlsx;*.ods'; '*.*'});
            
            if ~isequal(file, 0)
                app.VisualizationdataEditField.Value = [path file];
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            [file, path] = uigetfile({'*.bmp;*.jpg;*.jpeg;*.pbm;*;pgm;*.ppm;*.png;*.tif;*.tiff'; '*.*'});
            
            if ~isequal(file, 0)
                app.MapfileEditField.Value = [path file];
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            [file, path] = uiputfile({'*.avi'; '*.mp4'; '*.*'});
            
            if ~isequal(file, 0)
                app.OutputfileEditField.Value = [path file];
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.PreviewButton = uibutton(app.GridLayout4, 'push');
            app.PreviewButton.Text = 'Preview';
            app.RenderButton = uibutton(app.GridLayout4, 'push');
            app.RenderButton.Text = 'Render';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.Button = uibutton(app.GridLayout6, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '...';
            app.CoordinatesdataEditFieldLabel = uilabel(app.GridLayout6);
            app.CoordinatesdataEditFieldLabel.Text = 'Coordinates data';
            app.CoordinatesdataEditField = uieditfield(app.GridLayout6, 'text');
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.VisualizationdataEditFieldLabel = uilabel(app.GridLayout7);
            app.VisualizationdataEditFieldLabel.Text = 'Visualization data';
            app.VisualizationdataEditField = uieditfield(app.GridLayout7, 'text');
            app.Button_2 = uibutton(app.GridLayout7, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '...';
            app.GridLayout8 = uigridlayout(app.GridLayout5);
            app.MapfileEditFieldLabel = uilabel(app.GridLayout8);
            app.MapfileEditFieldLabel.Text = 'Map file';
            app.MapfileEditField = uieditfield(app.GridLayout8, 'text');
            app.Button_3 = uibutton(app.GridLayout8, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '...';
            app.GridLayout9 = uigridlayout(app.GridLayout5);
            app.OutputfileEditFieldLabel = uilabel(app.GridLayout9);
            app.OutputfileEditFieldLabel.Text = 'Output file';
            app.OutputfileEditField = uieditfield(app.GridLayout9, 'text');
            app.Button_4 = uibutton(app.GridLayout9, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '...';
            app.GridLayout10 = uigridlayout(app.GridLayout5);
            app.CheckBox = uicheckbox(app.GridLayout10);
            app.CheckBox.Text = '';
            app.EnableparallelizationLabel = uilabel(app.GridLayout10);
            app.EnableparallelizationLabel.Text = 'Enable parallelization';
            app.GridLayout11 = uigridlayout(app.GridLayout5);
            app.CheckBox_2 = uicheckbox(app.GridLayout11);
            app.CheckBox_2.Text = '';
            app.InterpolatetimestepsindataLabel = uilabel(app.GridLayout11);
            app.InterpolatetimestepsindataLabel.Text = 'Interpolate time steps in data';
            app.GridLayout12 = uigridlayout(app.GridLayout5);
            app.MaximumresolutionLabel = uilabel(app.GridLayout12);
            app.MaximumresolutionLabel.Text = 'Maximum resolution';
            app.Label = uilabel(app.GridLayout12);
            app.Label.Text = '';
            app.EditField = uieditfield(app.GridLayout12, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.RoundFractionalValues = 'on';
            app.EditField.ValueDisplayFormat = '%u';
            app.EditField.Value = 1280;
            app.EditField_2 = uieditfield(app.GridLayout12, 'numeric');
            app.EditField_2.Limits = [0 Inf];
            app.EditField_2.RoundFractionalValues = 'on';
            app.EditField_2.ValueDisplayFormat = '%u';
            app.EditField_2.Value = 720;
            app.GridLayout13 = uigridlayout(app.GridLayout5);
            app.DatarangeLabel = uilabel(app.GridLayout13);
            app.DatarangeLabel.Text = 'Data range';
            app.EditField_5Label = uilabel(app.GridLayout13);
            app.EditField_5Label.Text = '';
            app.EditField_5 = uieditfield(app.GridLayout13, 'numeric');
            app.Label_3 = uilabel(app.GridLayout13);
            app.Label_3.Text = '';
            app.EditField_4 = uieditfield(app.GridLayout13, 'numeric');
            app.EditField_4.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.Image = uiimage(app.GridLayout2);
            app.Image2 = uiimage(app.GridLayout2);
            app.Image3 = uiimage(app.GridLayout2);
            app.Image4 = uiimage(app.GridLayout2);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end